classdef bonus_task < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BrowseButton                    matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        CoronalAxes                     matlab.ui.control.UIAxes
        SagittalAxes                    matlab.ui.control.UIAxes
        AxialAxes                       matlab.ui.control.UIAxes
        ObliqueAxes                     matlab.ui.control.UIAxes
        PolyButton                      matlab.ui.control.Button
        LineButton                      matlab.ui.control.Button
        AngleButton                     matlab.ui.control.Button
        EllipseButton                   matlab.ui.control.Button
        MultiplanarReconstructionLabel  matlab.ui.control.Label
        ResetButton                     matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        x = 0 % x-dimesion of 3D volume
        y = 0 % y-dimesion of 3D volume
        z = 0 % z-dimesion of 3D volume
        volume % 3D volume of images
        sagittalVol % sagittal plane slices
        coronalVol % coronal plane slices
        axialVol % axial plane slices
        imAxial % current axial slice on display
        imSagittal % current sagittal slice on display
        imCoronal % current coronal slice on display
        imOblique % current oblique slice on display
        H_AxialPos = [0 0; 0 0] % Horizontal position of axial crosshair
        V_AxialPos = [0 0; 0 0] % Vertical position of axial crosshair
        AxialHorizontalLine
        AxialVerticalLine
        H_SagittalPos = [0 0; 0 0] % Horizontal position of sagittal crosshair
        V_SagittalPos = [0 0; 0 0] % Vertical position of sagittal crosshair
        SagittalHorizontalLine
        SagittalVerticalLine 
        H_CoronalPos = [0 0; 0 0] % Horizontal position of sagittal crosshair
        V_CoronalPos = [0 0; 0 0] % Vertical position of sagittal crosshair
        CoronalVerticalLine 
        CoronalHorizontalLine
        ObliquePos = [0 1; 1 0] % Oblique Line position on Axial axis
        ObliqueLine
        IntersectedPoint = [0 0]
        Coeff
        xP % x-values of 2 points to define a line
        yP  % y-values of 2 points to define a line
        norm_a   % normal line
        slope = 1  % slope of oblique line
        intercept = 0 % intercept of oblique line
        PixelSpacing
    end
    
    methods (Access = private)
        
        function getAxial(app, depth)
            
            % Show on UI
            app.imAxial = app.axialVol(:,:,depth);
            axis(app.AxialAxes, 'tight');
            imshow(app.imAxial,[],'parent',app.AxialAxes);
        end
        
        function getSagittal(app, depth)
            
            for i= 1:app.y
                % Choose i-th slice in y-axis to be the singleton dimension to get x-z Plane
                % x-z plane now represents the i-th slice in the sagittal volume
                app.sagittalVol(:,:,i) = imrotate(squeeze(app.volume(:,i,:)),90); 
            end
            
             showSagittal(app,depth);
        end
        
        function showSagittal(app, depth)
            
            % Show on UI
            app.imSagittal = app.sagittalVol(:,:,ceil(depth));
            axis(app.SagittalAxes, 'tight');
            imshow(app.imSagittal,[],'parent',app.SagittalAxes);
        end
        
        function getCoronal(app, depth)
            for i= 1:app.x 
                % Choose i-th slice in x-axis to be the singleton dimension to get y-z Plane
                % y-z plane now represents the i-th slice in the coronal volume
                app.coronalVol(:,:,i) = imrotate(squeeze(app.volume(i,:,:)),90);
            end
            showCoronal(app, depth);
        end
        
       function showCoronal(app, depth)  
            % Show on UI
            app.imCoronal = app.coronalVol(:,:,ceil(depth));
            axis(app.CoronalAxes, 'tight');
            imshow(app.imCoronal,[],'parent',app.CoronalAxes);
        end
             
        function clearAxes(app)
            cla(app.AxialAxes);
            cla(app.SagittalAxes);
            cla(app.CoronalAxes);
            cla(app.SagittalAxes);
        end
        
        function drawAxialCrossHairs(app)
            % Axial Horizontal Crosshair handling
            app.AxialHorizontalLine = drawline('Parent',app.AxialAxes,'Position',app.H_AxialPos,'LineWidth',1,'Color','R','InteractionsAllowed','translate');
            addlistener(app.AxialHorizontalLine,'ROIMoved',@(src,data)Update_H_AxialROI(app,data));
              
           % Axial Horizontal Crosshair handling
            app.AxialVerticalLine = drawline('Parent',app.AxialAxes,'Position',app.V_AxialPos,'LineWidth',1,'Color','G','InteractionsAllowed','translate');
            addlistener(app.AxialVerticalLine,'ROIMoved',@(src,data)Update_V_AxialROI(app,data));
        end
        
        function drawSagittalCrossHairs(app)
            % Sagittal Horizontal Crosshair handling
            % same behaviour as CoronalHorizontalLine
            app.SagittalHorizontalLine = drawline('Parent',app.SagittalAxes,'Position',app.H_SagittalPos,'LineWidth',1,'Color','B','InteractionsAllowed','translate');
            addlistener(app.SagittalHorizontalLine,'ROIMoved',@(src,data)Update_H_SagittalROI(app,data));
            
            % Sagittal Vertical Crosshair handling
            % same behaviour as AxialHorizontalLine
            app.SagittalVerticalLine = drawline('Parent',app.SagittalAxes,'Position',app.V_SagittalPos,'LineWidth',1,'Color','R','InteractionsAllowed','translate');
            addlistener(app.SagittalVerticalLine,'ROIMoved',@(src,data)Update_V_SagittalROI(app,data));
            
        end
        
        function drawCoronalCrossHairs(app)
            % Coronal Horizontal Crosshair handling
            % same behaviour as SagittalHorizontalLine
            app.CoronalHorizontalLine = drawline('Parent',app.CoronalAxes,'Position',app.H_CoronalPos,'LineWidth',1,'Color','B','InteractionsAllowed','translate');
            addlistener(app.CoronalHorizontalLine,'ROIMoved',@(src,data)Update_H_CoronalROI(app,data));
            
            % Coronal Vertical Crosshair handling
            % same behaviour as AxialVerticalLine
            app.CoronalVerticalLine = drawline('Parent',app.CoronalAxes,'Position',app.V_CoronalPos,'LineWidth',1,'Color','G','InteractionsAllowed','translate');
            addlistener(app.CoronalVerticalLine,'ROIMoved',@(src,data)Update_V_CoronalROI(app,data));
           
        end
        
        function Update_H_AxialROI(app,data)
            pos = round(data.CurrentPosition); 
            depth = pos(2,2);
            if depth > app.y
                depth = app.y;
            end
            app.H_AxialPos = [1 depth; app.x depth];
            
            % Update Coronal Depth 
            getCoronal(app,depth);
            drawCoronalCrossHairs(app);
            
            % Update Sagittal vertical Axis 
            app.V_SagittalPos = [depth 1; depth app.z];
            app.SagittalVerticalLine.Position = app.V_SagittalPos;
           
        end
        
        function Update_V_AxialROI(app,data)
            pos = round(data.CurrentPosition); 
            depth = pos(1,1);
            if depth > app.x
                depth = app.x;
            end
            app.V_AxialPos = [depth 1; depth app.y];
                 
            % Update Sagittal Depth 
            getSagittal(app,depth);
            drawSagittalCrossHairs(app);
            
            % Update Coronal vertical Axis 
            app.V_CoronalPos = [depth 1; depth app.z];
            app.CoronalVerticalLine.Position = app.V_CoronalPos;
            
        end
        
        function Update_H_SagittalROI (app,data)
            pos = round(data.CurrentPosition); 
            depth = pos(2,2);
            if depth > app.z
                depth = app.z -1;
            end
            app.H_SagittalPos = [1 depth; app.y depth];
            
            % Update Axial Depth 
            getAxial(app,app.z - depth);
            drawAxialCrossHairs(app);
            drawObliqueCrossHairs(app);
            
            % Update CoronalAxis 
            app.H_CoronalPos = [1 depth; app.x depth];
            app.CoronalHorizontalLine.Position = app.H_CoronalPos;
        end
        
        function Update_V_SagittalROI (app,data)
            pos = round(data.CurrentPosition); 
            depth = pos(1,1);
            if depth > app.y
                depth = app.y;
            end
            app.V_SagittalPos = [depth 1; depth app.z];
            
            % Update Coronal Depth 
            getCoronal(app,depth);
            drawCoronalCrossHairs(app);
            
            % Update AxialAxis 
            app.H_AxialPos = [1 depth; app.x depth];
            app.AxialHorizontalLine.Position = app.H_AxialPos;
        end
        
        function Update_H_CoronalROI(app,data)
            pos = round(data.CurrentPosition); 
            depth = pos(2,2);
            if depth > app.z
                depth = app.z - 1;
            end
            app.H_CoronalPos = [1 depth; app.x depth];
            
            % Update Axial Depth 
            getAxial(app,app.z - depth);
            drawAxialCrossHairs(app);
            drawObliqueCrossHairs(app);
            
            %update SagittalAxis
            app.H_SagittalPos = [1 depth; app.y depth];
            app.SagittalHorizontalLine.Position = app.H_SagittalPos;
    
        end
        
        function Update_V_CoronalROI(app,data)
            pos = round(data.CurrentPosition); 
            depth = pos(1,1);
            if depth > app.x
                depth = app.x;
            end            
            app.V_CoronalPos = [depth 1; depth app.z];
            
            % Update Sagittal Depth
            getSagittal(app,depth);
            drawSagittalCrossHairs(app);
            
            %update AxialAxis
            app.V_AxialPos = [depth 1; depth app.y];
            app.AxialVerticalLine.Position = app.V_AxialPos;
    
        end
        
        function drawInitialCrossHairs(app)
            % axial axes         
            app.H_AxialPos = [1 ceil(app.y/2); app.x ceil(app.y/2)];
            app.V_AxialPos = [ceil(app.x/2) 1; ceil(app.x/2) app.y];
            % sagittal axes
            app.H_SagittalPos = [1 ceil(app.z/2); app.y ceil(app.z/2)];
            app.V_SagittalPos = [ceil(app.y/2) 1; ceil(app.y/2) app.z];
            %coronal axes
            app.H_CoronalPos = [1 ceil(app.z/2); app.x ceil(app.z/2)];
            app.V_CoronalPos = [ceil(app.x/2) 1; ceil(app.x/2) app.z];
            % oblique axes
            app.ObliquePos = [app.x/4 app.y/4; 3/4*app.x 3/4*app.y]; 
        end
        
        function drawObliqueCrossHairs(app)
            % Axial Oblique Crosshair handling
            app.ObliqueLine = drawline('Parent',app.AxialAxes,'Position',app.ObliquePos,'LineWidth',1,'Color','C','InteractionsAllowed','all');
            addlistener(app.ObliqueLine,'ROIMoved',@(src,data)UpdateObliqueROI(app,data));
        end
        
        function EquationSTLine(app,pos)
            x1 = pos(1,1);
            y1 = pos(1,2);
                
            x2 = pos(2,1);
            y2 = pos(2,2);

            app.xP = [x1 x2];
            app.yP = [y1 y2];

            app.Coeff = polyfit(app.xP,app.yP,1);
            
            m = app.Coeff(1);
            c = app.Coeff(2);
            app.slope = m;
            app.intercept = c;  
        end
        
        function UpdateObliqueROI(app,data)
            pos = round(data.CurrentPosition);
            newpos = round(data.CurrentPosition);
            
            EquationSTLine(app,pos);
            
            app.ObliquePos = round(newpos);
            app.ObliqueLine.Position = app.ObliquePos;
            
            % Draw oblique slice
            showOblique(app);
        end
            
        function getNormalLine(app,X,Y)
            % Its direction:
            v = [diff(X); diff(Y); 0];
            n = v / vecnorm(v);  % Normalized
            
            % Orthogonal means dot(n,m) = 0 or:
            % n(1) * m(1) + n(2) * m(2) = 0
            
            % There are 2 solutions:
            if (diff(Y) < 0)
                app.norm_a = [n(2) -n(1) 0];
            else
                app.norm_a = [-n(2) n(1) 0];
            end

        end
        
        function showOblique(app)
            getNormalLine(app,app.xP,app.yP);
            
            point = [app.xP(1) app.yP(1) 0];
            normal = app.norm_a;

            [B,a,b,c] = obliqueslice(app.volume,point,normal, 'OutputSize','full');
              
            % Rotate image
             ang =atan(app.slope)* 180 / pi;
             
             if ang < 0
                 angle = 180 + ang;
             else
                 angle = ang;
             end

            B2 = imrotate(B,angle);

            arr = [app.x,app.y,app.z];
            
            vMAx = max(arr);
            dim = size(B2);
            
            if dim(1) > vMAx
                n = floor((dim(1) - vMAx)/2);
                app.imOblique = B2(n+1:n+vMAx,:);
            else
                app.imOblique = B2;
            end
            if dim(2) > vMAx
                n = floor((dim(2) - vMAx)/2);
                app.imOblique = app.imOblique(:,n+1:n+vMAx);
            end
            
            axis(app.ObliqueAxes, 'tight');
            imshow(app.imOblique,[],'parent',app.ObliqueAxes);
        end
        
        function Update_Line(app,src)
            calcLineInfo(app,src);
        end
        
        function calcLineInfo(app,line)
            x_pos = line.Position(:,1);
            y_pos = line.Position(:,2);
            distance_in_mm = sqrt(((x_pos(1)-x_pos(2))*app.PixelSpacing(1))^2 + ((y_pos(1)-y_pos(2))*app.PixelSpacing(2))^2);
            line.Label = " " + distance_in_mm + " mm";
        end
        
        function Update_Polygon(app,src)
            calcPolygonInfo(app,src);
        end
        
        function calcPolygonInfo(app,poly_roi)
            x_pos = poly_roi.Position(:,1);
            y_pos = poly_roi.Position(:,2);
            area_polygon = polyarea(x_pos,y_pos);
            area_polygon_in_mm = area_polygon * app.PixelSpacing(1) * app.PixelSpacing(2);
            poly_roi.Label = " " + area_polygon_in_mm + " mm2";
        end
        
        function Update_Angle(app,src)
            calcAngleInfo(app,src);
        end
        
        function calcAngleInfo(app,two_lines)
            x_pos = two_lines.Position(:,1);
            y_pos = two_lines.Position(:,2);
            v_1 = [x_pos(2),y_pos(2),0] - [x_pos(1),y_pos(1),0];
            v_2 = [x_pos(3),y_pos(3),0] - [x_pos(2),y_pos(2),0];
            Theta = 180 - (atan2(norm(cross(v_1, v_2)), dot(v_1, v_2))* 180 / pi);
            two_lines.Label = " " + Theta + " degree";
        end
        
        function Update_Ellipse(app,src)
            calcEllipseInfo(app,src);
        end
        
        function calcEllipseInfo(app,ellipse_roi)
            area_ellipse_in_mm = pi * ellipse_roi.SemiAxes(1) * ellipse_roi.SemiAxes(2) * app.PixelSpacing(1) * app.PixelSpacing(2);
            ellipse_roi.Label = " " + area_ellipse_in_mm + " mm2";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            path = uigetdir('Open Dicom Directory');
            figure(app.UIFigure);
            
            try 
                % Clear all axes
                clearAxes(app);
                
                % Create a 4-D volume, V, from a set of DICOM files specified by source. 
                % The dicomreadVolume function identifies the correct order of the images and creates a 4-D volume.
                [V,spatial,dim] = dicomreadVolume(path);
                
                % Use the squeeze function to remove any singleton dimensions.
                app.volume = squeeze(V);
                app.axialVol = app.volume;
                
                % Get the volume dimesnions.
                [app.x,app.y,app.z] = size(app.volume);
                
                % Construct empty volumes for the 2 other planes.
                app.sagittalVol = zeros(app.z ,app.x, app.y);  % x-z Plane
                app.coronalVol = zeros( app.z, app.y, app.x);  % y-z Plane
                
                % Get Axial Plane
                getAxial(app, ceil(app.z/2));
                
                % Get Sagittal Plane
                getSagittal(app, ceil(app.y/2));
                
                % Get Coronal Plane
                getCoronal(app, ceil(app.x/2));
                
                       
                %initialize crosshairs positions
                drawInitialCrossHairs(app);
                
                %CrossHairs
                drawAxialCrossHairs(app);
                disableDefaultInteractivity(app.AxialAxes);
                
                drawSagittalCrossHairs(app);
                disableDefaultInteractivity(app.SagittalAxes);
                
                drawCoronalCrossHairs(app);
                disableDefaultInteractivity(app.CoronalAxes);
                
                drawObliqueCrossHairs(app);
                disableDefaultInteractivity(app.ObliqueAxes);
                
                % Show Oblique Plane
                EquationSTLine(app,app.ObliquePos)
                showOblique(app);
                
                app.PixelSpacing = spatial.PixelSpacings(1,:);
                
            catch
                errordlg('Path is a non-dicom directory','Error')
            end

        end

        % Value changed function: ResetButton
        function ResetButtonValueChanged(app, event)
            % Clear all axes
            clearAxes(app);
            
            % Get Axial Plane
            getAxial(app, ceil(app.z/2));
            
            % Get Sagittal Plane
            getSagittal(app, ceil(app.y/2));
            
            % Get Coronal Plane
            getCoronal(app, ceil(app.x/2));
            
            %initialize crosshairs positions
            drawInitialCrossHairs(app);
            
            %CrossHairs
            drawAxialCrossHairs(app);
            disableDefaultInteractivity(app.AxialAxes);
            
            drawSagittalCrossHairs(app);
            disableDefaultInteractivity(app.SagittalAxes);
            
            drawCoronalCrossHairs(app);
            disableDefaultInteractivity(app.CoronalAxes);
            
            drawObliqueCrossHairs(app);
            disableDefaultInteractivity(app.ObliqueAxes);
                
%             Show Oblique Plane
            EquationSTLine(app,app.ObliquePos)
            showOblique(app);
            
        end

        % Button pushed function: PolyButton
        function PolyButtonPushed(app, event)
            if app.x ~= 0              
                poly_roi = drawpolygon('Parent',app.ObliqueAxes, 'Color','r', 'InteractionsAllowed', 'all');
                addlistener(poly_roi,'ROIMoved',@(src,data)Update_Polygon(app,src));
                calcPolygonInfo(app,poly_roi);
            end

        end

        % Button pushed function: LineButton
        function LineButtonPushed(app, event)
            if app.x ~= 0
                line = drawline('Parent',app.ObliqueAxes,'Color','C', 'InteractionsAllowed', 'all');
                addlistener(line,'ROIMoved',@(src,data)Update_Line(app,src));
                calcLineInfo(app,line);
            end
        end

        % Button pushed function: AngleButton
        function AngleButtonPushed(app, event)
            if app.x ~= 0
                two_lines = drawpolyline('Parent',app.ObliqueAxes,'Color','g','InteractionsAllowed', 'all');
                addlistener(two_lines,'ROIMoved',@(src,data)Update_Angle(app,src));
                calcAngleInfo(app,two_lines);  
            end
        end

        % Button pushed function: EllipseButton
        function EllipseButtonPushed(app, event)
            if app.x ~= 0
                ellipse_roi = drawellipse('Parent',app.ObliqueAxes,'Color','b','InteractionsAllowed', 'all');
                addlistener(ellipse_roi,'ROIMoved',@(src,data)Update_Ellipse(app,src));
                calcEllipseInfo(app,ellipse_roi); 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Icon = 'BrowseIcon.png';
            app.BrowseButton.Text = 'Browse';
            app.Panel = uipanel(app.UIFigure);
            app.CoronalAxes = uiaxes(app.Panel);
            title(app.CoronalAxes, 'Coronal')
            xlabel(app.CoronalAxes, 'X')
            ylabel(app.CoronalAxes, 'Z')
            app.CoronalAxes.XTickLabel = '';
            app.CoronalAxes.YTickLabel = '';
            app.SagittalAxes = uiaxes(app.Panel);
            title(app.SagittalAxes, 'Sagittal')
            xlabel(app.SagittalAxes, 'Y')
            ylabel(app.SagittalAxes, 'Z')
            app.SagittalAxes.XTickLabel = '';
            app.SagittalAxes.YTickLabel = '';
            app.AxialAxes = uiaxes(app.Panel);
            title(app.AxialAxes, 'Axial')
            xlabel(app.AxialAxes, 'X')
            ylabel(app.AxialAxes, 'Y')
            app.AxialAxes.XTickLabel = '';
            app.AxialAxes.YTickLabel = '';
            app.ObliqueAxes = uiaxes(app.Panel);
            title(app.ObliqueAxes, 'Oblique')
            xlabel(app.ObliqueAxes, '')
            ylabel(app.ObliqueAxes, '')
            app.ObliqueAxes.XTickLabel = '';
            app.ObliqueAxes.YTickLabel = '';
            app.PolyButton = uibutton(app.Panel, 'push');
            app.PolyButton.ButtonPushedFcn = createCallbackFcn(app, @PolyButtonPushed, true);
            app.PolyButton.Text = 'Poly';
            app.LineButton = uibutton(app.Panel, 'push');
            app.LineButton.ButtonPushedFcn = createCallbackFcn(app, @LineButtonPushed, true);
            app.LineButton.Text = 'Line';
            app.AngleButton = uibutton(app.Panel, 'push');
            app.AngleButton.ButtonPushedFcn = createCallbackFcn(app, @AngleButtonPushed, true);
            app.AngleButton.Text = 'Angle';
            app.EllipseButton = uibutton(app.Panel, 'push');
            app.EllipseButton.ButtonPushedFcn = createCallbackFcn(app, @EllipseButtonPushed, true);
            app.EllipseButton.Text = 'Ellipse';
            app.MultiplanarReconstructionLabel = uilabel(app.UIFigure);
            app.MultiplanarReconstructionLabel.Text = 'Multiplanar Reconstruction';
            app.ResetButton = uibutton(app.UIFigure, 'state');
            app.ResetButton.ValueChangedFcn = createCallbackFcn(app, @ResetButtonValueChanged, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bonus_task

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end