classdef task2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BrowseButton                    matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        CoronalAxes                     matlab.ui.control.UIAxes
        SagittalAxes                    matlab.ui.control.UIAxes
        AxialAxes                       matlab.ui.control.UIAxes
        ObliqueAxes                     matlab.ui.control.UIAxes
        AxialSliderLabel                matlab.ui.control.Label
        AxialSlider                     matlab.ui.control.Slider
        SagittalSliderLabel             matlab.ui.control.Label
        SagittalSlider                  matlab.ui.control.Slider
        CoronalSliderLabel              matlab.ui.control.Label
        CoronalSlider                   matlab.ui.control.Slider
        ObliqueSliderLabel              matlab.ui.control.Label
        ObliqueSlider                   matlab.ui.control.Slider
        MultiplanarReconstructionLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        x % x-dimesion of 3D volume
        y % y-dimesion of 3D volume
        z % z-dimesion of 3D volume
        volume % 3D volume of images
        sagittalVol % sagittal plane slices
        coronalVol % coronal plane slices
        axialVol % axial plane slices
        imAxial % current axial slice on display
        imSagittal % current sagittal slice on display
        imCoronal % current coronal slice on display
        axialPos = [50 50] % position of axial crosshair
        sagittalPos = [50 50] % position of sagittal crosshair
        coronalPos = [50 50] % % position of coronal crosshair
    end
    
    methods (Access = private)
        
        function getAxial(app, depth)
            
            % Show on UI
            app.imAxial = app.axialVol(:,:,ceil(depth));
            axis(app.AxialAxes, 'tight');
            imshow(app.imAxial,[],'parent',app.AxialAxes);
        end
        
        function getSagittal(app, depth)
            
            for i= 1:app.y
                % Choose i-th slice in y-axis to be the singleton dimension to get x-z Plane
                % x-z plane now represents the i-th slice in the sagittal volume
                app.sagittalVol(:,:,i) = squeeze(app.volume(:,i,:)); 
            end
            
             showSagittal(app,depth);
        end
        
        function showSagittal(app, depth)
            
            % Show on UI
            app.imSagittal = app.sagittalVol(:,:,ceil(depth));
            axis(app.SagittalAxes, 'tight');
            imshow(app.imSagittal,[],'parent',app.SagittalAxes);
        end
        
        function getCoronal(app, depth)
            for i= 1:app.x 
                % Choose i-th slice in x-axis to be the singleton dimension to get y-z Plane
                % y-z plane now represents the i-th slice in the coronal volume
                app.coronalVol(:,:,i) = squeeze(app.volume(i,:,:));
            end
            showCoronal(app, depth);
        end
        
       function showCoronal(app, depth)  
            % Show on UI
            app.imCoronal = app.coronalVol(:,:,ceil(depth));
            axis(app.CoronalAxes, 'tight');
            imshow(app.imCoronal,[],'parent',app.CoronalAxes);
        end
             
        function clearAxes(app)
            cla(app.AxialAxes);
            cla(app.SagittalAxes);
            cla(app.CoronalAxes);
            cla(app.SagittalAxes);
        end
        
        function drawAxialCrossHairs(app)
            % Axial Crosshair handling
            AxialCrossHair = drawcrosshair('Parent',app.AxialAxes,'Position',app.axialPos,'LineWidth',1,'Color','R');
            addlistener(AxialCrossHair,'MovingROI',@(src,data)UpdateROI(app,src,data,app.AxialAxes,app.imAxial));
        end
        function drawSagittalCrossHairs(app)
            % Sagittal Crosshair handling
            SagittalCrossHair = drawcrosshair('Parent',app.SagittalAxes,'Position',app.sagittalPos,'LineWidth',1,'Color','R');
            addlistener(SagittalCrossHair,'MovingROI',@(src,data)UpdateROI(app,src,data,app.SagittalAxes,app.imSagittal));
        end
        function drawCoronalCrossHairs(app)
            % Coronal Crosshair handling
            CoronalCrossHair = drawcrosshair('Parent',app.CoronalAxes,'Position',app.coronalPos,'LineWidth',1,'Color','R');
            addlistener(CoronalCrossHair,'MovingROI',@(src,data)UpdateROI(app,src,data,app.CoronalAxes,app.imCoronal));
            
        end
        
        function UpdateROI(app, src,data,axis,img)
            axisName = axis.Title.String;
            pos = ceil(data.CurrentPosition);
            disp(pos);
            
            switch axisName
                case 'Axial'
                    app.axialPos = pos;
                    disp('Axial')
                case 'Sagittal'
                    app.sagittalPos = pos;
                    disp('Sagittal')
                case 'Coronal'
                    app.coronalPos = pos;
                    disp('Coronal')
                otherwise
                    disp('None')
            end
            
        end


        function resetSliders(app)
            app.AxialSlider.Value = 1;
            app.SagittalSlider.Value = 1;
            app.CoronalSlider.Value = 1;
            app.ObliqueSlider.Value = 1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            path = uigetdir('Open Dicom Directory');
            figure(app.UIFigure);
            
%             try 
                % Clear all axes
                clearAxes(app);
                % Reset Sliders
                resetSliders(app);
                
                % Create a 4-D volume, V, from a set of DICOM files specified by source. 
                % The dicomreadVolume function identifies the correct order of the images and creates a 4-D volume.
                [V,spatial,dim] = dicomreadVolume(path);
                
                % Use the squeeze function to remove any singleton dimensions.
                app.volume = squeeze(V);
                app.axialVol = app.volume;
                
                % Get the volume dimesnions.
                [app.x,app.y,app.z] = size(app.volume);
                
                % Construct empty volumes for the 2 other planes.
                app.sagittalVol = zeros(app.x ,app.z, app.y);  % x-z Plane
                app.coronalVol = zeros( app.y, app.z, app.x);  % y-z Plane
                
                % Get Axial Plane
                getAxial(app, app.z);
                
                % Get Sagittal Plane
                getSagittal(app, app.y);
                
                % Get Coronal Plane
                getCoronal(app, app.x);
                
                %CrossHairs
                drawAxialCrossHairs(app);
                drawSagittalCrossHairs(app);
                drawCoronalCrossHairs(app);
                
                % Set Axial, Sagittal, Coronal Slider Limits
                app.AxialSlider.Limits = [1 app.z];
                app.SagittalSlider.Limits = [1 app.y];
                app.CoronalSlider.Limits = [1 app.x];
                
%             catch
%                 errordlg('Path is a non-dicom directory','Error')
%             end

        end

        % Value changed function: AxialSlider
        function AxialSliderValueChanged(app, event)
            try
                depth = app.AxialSlider.Value;
                getAxial(app, depth);
                %CrossHairs
                drawAxialCrossHairs(app);
            catch
                errordlg('Error in getting volume slice','Error')
            end
        end

        % Value changed function: CoronalSlider
        function CoronalSliderValueChanged(app, event)
            try
                depth = app.CoronalSlider.Value;
                showCoronal(app, depth)
                %CrossHairs
                drawCoronalCrossHairs(app);
            catch
                errordlg('Error in getting volume slice','Error')   
            end
        end

        % Value changed function: SagittalSlider
        function SagittalSliderValueChanged(app, event)
            try
                depth = app.SagittalSlider.Value;
                showSagittal(app, depth);
                %CrossHairs
                drawSagittalCrossHairs(app);
            catch 
                errordlg('Error in getting volume slice','Error')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Icon = 'BrowseIcon.png';
            app.BrowseButton.Text = 'Browse';
            app.Panel = uipanel(app.UIFigure);
            app.CoronalAxes = uiaxes(app.Panel);
            title(app.CoronalAxes, 'Coronal')
            xlabel(app.CoronalAxes, '')
            ylabel(app.CoronalAxes, '')
            app.CoronalAxes.XTickLabel = '';
            app.CoronalAxes.YTickLabel = '';
            app.SagittalAxes = uiaxes(app.Panel);
            title(app.SagittalAxes, 'Sagittal')
            xlabel(app.SagittalAxes, '')
            ylabel(app.SagittalAxes, '')
            app.SagittalAxes.XTickLabel = '';
            app.SagittalAxes.YTickLabel = '';
            app.AxialAxes = uiaxes(app.Panel);
            title(app.AxialAxes, 'Axial')
            xlabel(app.AxialAxes, '')
            ylabel(app.AxialAxes, '')
            app.AxialAxes.XTickLabel = '';
            app.AxialAxes.YTickLabel = '';
            app.ObliqueAxes = uiaxes(app.Panel);
            title(app.ObliqueAxes, 'Oblique')
            xlabel(app.ObliqueAxes, '')
            ylabel(app.ObliqueAxes, '')
            app.ObliqueAxes.XTickLabel = '';
            app.ObliqueAxes.YTickLabel = '';
            app.AxialSliderLabel = uilabel(app.Panel);
            app.AxialSliderLabel.Text = 'AxialSlider';
            app.AxialSlider = uislider(app.Panel);
            app.AxialSlider.Limits = [1 100];
            app.AxialSlider.ValueChangedFcn = createCallbackFcn(app, @AxialSliderValueChanged, true);
            app.AxialSlider.Value = 1;
            app.SagittalSliderLabel = uilabel(app.Panel);
            app.SagittalSliderLabel.Text = 'SagittalSlider';
            app.SagittalSlider = uislider(app.Panel);
            app.SagittalSlider.Limits = [1 100];
            app.SagittalSlider.ValueChangedFcn = createCallbackFcn(app, @SagittalSliderValueChanged, true);
            app.SagittalSlider.Value = 1;
            app.CoronalSliderLabel = uilabel(app.Panel);
            app.CoronalSliderLabel.Text = 'CoronalSlider';
            app.CoronalSlider = uislider(app.Panel);
            app.CoronalSlider.Limits = [1 100];
            app.CoronalSlider.ValueChangedFcn = createCallbackFcn(app, @CoronalSliderValueChanged, true);
            app.CoronalSlider.Value = 1;
            app.ObliqueSliderLabel = uilabel(app.Panel);
            app.ObliqueSliderLabel.Text = 'ObliqueSlider';
            app.ObliqueSlider = uislider(app.Panel);
            app.ObliqueSlider.Limits = [1 100];
            app.ObliqueSlider.Value = 1;
            app.MultiplanarReconstructionLabel = uilabel(app.UIFigure);
            app.MultiplanarReconstructionLabel.Text = 'Multiplanar Reconstruction';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = task2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end